classdef NumericalMethodsSolver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        DerivativeEditField     matlab.ui.control.EditField
        DerivativeLabel         matlab.ui.control.Label
        FixedPointEditField     matlab.ui.control.EditField
        FixedPointLabel         matlab.ui.control.Label
        IterationTable          matlab.ui.control.Table
        StatusLabel_2           matlab.ui.control.Label
        StatusLabel             matlab.ui.control.Label
        SolutionLabel_2         matlab.ui.control.Label
        SolutionLabel           matlab.ui.control.Label
        SolveButton             matlab.ui.control.Button
        DropDown                matlab.ui.control.DropDown
        MethodDropDown          matlab.ui.control.Label
        EditField4              matlab.ui.control.NumericEditField
        MaxIterationsEditField  matlab.ui.control.Label
        EditField3              matlab.ui.control.NumericEditField
        ToleranceEditField      matlab.ui.control.Label
        EditField2              matlab.ui.control.NumericEditField
        InitialGuessEditField   matlab.ui.control.Label
        FunctionEditField       matlab.ui.control.EditField
        FunctionEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
                        f = str2func(['@(x) ' char(app.FunctionEditField.Value)]);
            g = str2func(['@(x) ' char(app.FixedPointEditField.Value)]);
            df = str2func(['@(x) ' char(app.DerivativeEditField.Value)]);
                            app.StatusLabel.Text = 'Button clicked';
            x = app.EditField2.Value;
            tol = app.EditField3.Value;
            maxIter = app.EditField4.Value;
            method = app.DropDown.Value;

            data = zeros(maxIter, 3);
            converged = false;

            for k = 1:maxIter
                if strcmp(method, 'Newton-Raphson')
                    slope = df(x);
                    if abs(slope) < eps
                        error('Derivative is zero or too small at the current guess.');
                    end
                    xNew = x - f(x)/slope;
                else
                    xNew = g(x);
                end

                data(k,:) = [k, xNew, abs(f(xNew))];

                if abs(xNew - x) < tol || abs(f(xNew)) < tol
                    converged = true;
                    x = xNew;
                    break
                end

                x = xNew;
            end

            app.IterationTable.Data = data(1:k,:);
            app.SolutionLabel.Text = num2str(x, '%.10g');

            if converged
                app.StatusLabel.Text = 'Converged';
            else
                app.StatusLabel.Text = 'Stopped: maximum iterations reached';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FunctionEditFieldLabel = uilabel(app.UIFigure);
            app.FunctionEditFieldLabel.Text = 'FunctionEditField';
            app.FunctionEditField = uieditfield(app.UIFigure, 'text');
            app.FunctionEditField.Value = 'x^2- 2';
            app.InitialGuessEditField = uilabel(app.UIFigure);
            app.InitialGuessEditField.Text = 'InitialGuessEditField';
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2.Value = 1;
            app.ToleranceEditField = uilabel(app.UIFigure);
            app.ToleranceEditField.Text = 'ToleranceEditField';
            app.EditField3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField3.Value = 0.0001;
            app.MaxIterationsEditField = uilabel(app.UIFigure);
            app.MaxIterationsEditField.Text = 'MaxIterationsEditField';
            app.EditField4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField4.Value = 50;
            app.MethodDropDown = uilabel(app.UIFigure);
            app.MethodDropDown.Text = 'MethodDropDown';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'fixed-point iteration', 'Newton Raphson', 'Option 3', 'Option 4'};
            app.DropDown.Value = 'fixed-point iteration';
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.SolutionLabel = uilabel(app.UIFigure);
            app.SolutionLabel.Text = 'Solution:';
            app.SolutionLabel_2 = uilabel(app.UIFigure);
            app.SolutionLabel_2.Text = 'SolutionLabel';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status:';
            app.StatusLabel_2 = uilabel(app.UIFigure);
            app.StatusLabel_2.Text = 'StatusLabel';
            app.IterationTable = uitable(app.UIFigure);
            app.IterationTable.ColumnName = {'Iteration'; 'x'; '|f(x)|'};
            app.IterationTable.RowName = {};
            app.FixedPointLabel = uilabel(app.UIFigure);
            app.FixedPointLabel.Text = 'FixedPointEditField';
            app.FixedPointEditField = uieditfield(app.UIFigure, 'text');
            app.FixedPointEditField.Value = '(x+2/x)/2';
            app.DerivativeLabel = uilabel(app.UIFigure);
            app.DerivativeLabel.Text = 'DerivativeEditField';
            app.DerivativeEditField = uieditfield(app.UIFigure, 'text');
            app.DerivativeEditField.Value = '2*x';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NumericalMethodsSolver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end